classdef SFG_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DatasortingTab                 matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        InformationhelpButton          matlab.ui.control.Button
        AdditionalSettingsButton       matlab.ui.control.Button
        SamplesLabel                   matlab.ui.control.Label
        ViswavelengthcalibrationLabel  matlab.ui.control.Label
        ReferencesLabel                matlab.ui.control.Label
        calibUITable                   matlab.ui.control.Table
        sampleUITable                  matlab.ui.control.Table
        refUITable                     matlab.ui.control.Table
        ApplychangesButton             matlab.ui.control.Button
        LoadandsortdatafilesPanel      matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        SelectthefilesyouwanttoprocessLabel  matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        CurrentdirectoryLabel          matlab.ui.control.Label
        wdField                        matlab.ui.control.EditField
        SetchangeworkingdirectoryButton  matlab.ui.control.Button
        SortfilesButton                matlab.ui.control.Button
        CalibrationstringEditField     matlab.ui.control.EditField
        CalibrationstringEditFieldLabel  matlab.ui.control.Label
        ReferencestringEditField       matlab.ui.control.EditField
        ReferencestringEditFieldLabel  matlab.ui.control.Label
        CalibrationTab                 matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        CalibratevisiblewavelengthLabel  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        CalibrationfileDropDown        matlab.ui.control.DropDown
        CalibrationfileDropDownLabel   matlab.ui.control.Label
        baselineSlider                 matlab.ui.control.Slider
        baselineSliderLabel            matlab.ui.control.Label
        multiplierSlider               matlab.ui.control.Slider
        multiplierSliderLabel          matlab.ui.control.Label
        xrangeSlider                   matlab.ui.control.RangeSlider
        xrangeSliderLabel              matlab.ui.control.Label
        rawcalibUIAxes                 matlab.ui.control.UIAxes
        processedcalibUIAxes           matlab.ui.control.UIAxes
        RawdataTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        SelectdatatoviewLabel_3        matlab.ui.control.Label
        Tree1                          matlab.ui.container.CheckBoxTree
        raxrangeSlider                 matlab.ui.control.RangeSlider
        xrangeLabel                    matlab.ui.control.Label
        rawUIAxes                      matlab.ui.control.UIAxes
        AdditionalcleaningTab          matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        ApplychangesButton_2           matlab.ui.control.Button
        MannuallyinterpolatenotyetimplementedLabel  matlab.ui.control.Label
        AutomaticcleaningparametersLabel  matlab.ui.control.Label
        EditSelectedButton_2           matlab.ui.control.Button
        cleaningUITable_2              matlab.ui.control.Table
        EditSelectedButton             matlab.ui.control.Button
        cleaningUITable                matlab.ui.control.Table
        ProcesseddataTab               matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        SelectdatatoviewLabel_4        matlab.ui.control.Label
        Tree2                          matlab.ui.container.CheckBoxTree
        ExportAllButton                matlab.ui.control.Button
        ExportSelectedButton           matlab.ui.control.Button
        xrangeSlider_2                 matlab.ui.control.RangeSlider
        xrangeSlider_2Label            matlab.ui.control.Label
        processedUIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ref_str           string              % string of characters used to indicate a file corresponds to a reference (e.g. "quartz")
        calibration_str   string              % string of characters used to indicate a file corresponds to a calibration (e.g. "polystyrene")
        vis_wl            double              % wavelength of the visible beam
        center_wl         double = NaN        % center IR wavelength in nm (only if detected and equal for all files, else is NaN)

        data_dir                              % directory in which to look for files
        dirWatcher        timer               % Timer object for watching directory
        lastFileList      cell = {}           % Last known list of .csv files in data_dir

        sample_files      SampleFile          % array of SampleFile objects containing the sample files
        bg_files          File                % array of File objects containing the background files
        ref_files         SignalFile          % array of SignalFile objects containing the reference files
        calibration_files SampleFile          % array of SignalFile objects containing the calibration files

        polystyrene_std                       % NIST data for polystyrene imaginary refractive index

        bkendSampleTable  table               % sample/bg/ref files match for samples files, saved as table
        bkendRefTable     table               % signal/bg files match for reference files, saved as table
        bkendCalibTable   table               % sample/bg/ref files match for calibration files, saved as table

        settingsApp                           % instance of the settings app
        cleaningApp                           % instance of the cosmic ray cleaning app
    end

    properties (Access = public)
        bandwidth      double  = 1000         % bandwidth of the IR pulse in cm-1
        simon          logical = false        % use simon's secret options if true
    end

    % ==== File handling ====
    methods (Access = private) 

        function processFiles(app)
            % process reference files
            for i = 1:height(app.bkendRefTable)
                signal = app.bkendRefTable.Signal(i);
                background = app.bkendRefTable.Background(i);

                signal.bg = background; % Assign background
            end

            % process sample files
            for i = 1:height(app.bkendSampleTable)
                sample = app.bkendSampleTable.Sample(i);
                background = app.bkendSampleTable.Background(i);
                reference = app.bkendSampleTable.Reference(i);

                % Assign the background and reference
                sample.bg = background;
                sample.ref = reference;

                % subtract bg, normalize, convert to wavenumber
                sample.process;
                sample.convert_to_wn(app.vis_wl);
            end

            % process calibration files
            for i = 1:height(app.bkendCalibTable)
                calib = app.bkendCalibTable.Calibration(i);
                background = app.bkendCalibTable.Background(i);
                reference = app.bkendCalibTable.Reference(i);

                % Assign the background and reference
                calib.bg = background;
                calib.ref = reference;

                % subtract bg, normalize, convert to wavenumber
                calib.process;
                calib.convert_to_wn(app.vis_wl);

                % convert from transmittance to absorbance
                calib.processed_data.Intensity(calib.processed_data.Intensity <= 0) = eps;
                calib.processed_data.Absorbance = -log10(calib.processed_data.Intensity);
            end

        end

        function bgFilename = matchBackground(app, signal)
            if isempty(signal.info)
                bgFilename = '';
                return;
            end

            sig_time = str2double(signal.info.time);

            best_diff = inf;
            bgFilename = [];

            for i = 1:numel(app.bg_files)
                f = app.bg_files(i);

                % Check for matching experimental conditions
                if strcmp(f.info.polarization, signal.info.polarization) && ...
                        f.info.region == signal.info.region && ...
                        strcmp(f.info.concentration, signal.info.concentration) && ...
                        strcmp(f.info.condition, signal.info.condition) && ...
                        strcmp(f.info.condition2, signal.info.condition2) && ...
                        strcmp(f.info.sample, signal.info.sample) && ...
                        f.info.acqtime == signal.info.acqtime

                    time = str2double(f.info.time);
                    dt = abs(time - sig_time);
                    if dt < best_diff
                        best_diff = dt;
                        bgFilename = f.filename;
                    end
                end
            end

            if isempty(bgFilename)
                bgFilename = '';
            end
        end

        function refFilename = matchRef(app, sample)
            if isempty(sample.info)
                refFilename = '';
                return;
            end
            sig_time = str2double(sample.info.time);

            best_diff = inf;
            refFilename = [];

            for i = 1:numel(app.ref_files)
                f = app.ref_files(i);

                % Check for matching experimental conditions
                if strcmp(f.info.polarization, sample.info.polarization) && ...
                        f.info.region == sample.info.region

                    time = str2double(f.info.time);
                    dt = abs(time - sig_time);
                    if dt < best_diff
                        best_diff = dt;
                        refFilename = f.filename;
                    end
                end
            end

            if isempty(refFilename)
                refFilename = '';
            end
        end

        function files = selectedFiles(app, uitable)
            % Get selected rows
            selectedRows = uitable.Selection;
            if isempty(selectedRows)
                files = {};
                return;
            end

            % Get filenames from the first column of selected rows
            selectedFilenames = cell(numel(selectedRows), 1);
            for i = 1:numel(selectedRows)
                rowIdx = selectedRows(1, i);
                selectedFilenames{i} = uitable.Data{rowIdx, 1};
            end

            % Initialize output
            files = cell(numel(selectedRows), 1);

            % Define file arrays to search
            fileArrays = {app.sample_files, app.bg_files, app.ref_files, app.calibration_files};

            % For each selected filename, search all file arrays
            for i = 1:numel(selectedFilenames)
                fname = string(selectedFilenames{i});
                found = false;
                for j = 1:numel(fileArrays)
                    thisArray = fileArrays{j};
                    for k = 1:numel(thisArray)
                        fileObj = thisArray(k);
                        if strcmp(fileObj.filename, fname)
                            files{i} = fileObj;
                            found = true;
                            break; % Stop once we find the first match
                        end
                    end
                    if found
                        break;
                    end
                end
            end
        end

        function export(app, sampleFiles)
            % Ask user for export directory
            export_dir = uigetdir(string(app.data_dir), "Select Export Directory");
            if isequal(export_dir, 0)
                return; % user canceled
            end

            for f = sampleFiles
                try
                    % Extract Wavenumber and Intensity columns
                    data = f.processed_data(:, {'Wavenumber', 'Intensity'});

                    % Build CSV file name
                    [~, name] = fileparts(f.filename);
                    csv_name = fullfile(export_dir, string(name) + "_processed.csv");

                    % Open file for writing
                    fid = fopen(csv_name, "w");
                    if fid == -1
                        error("Could not open file: %s", csv_name);
                    end

                    % ---- Write metadata header ----
                    fprintf(fid, "# Processed data export\n");
                    fprintf(fid, "# visible wavelength used : %g nm\n", app.vis_wl);
                    fprintf(fid, "# Background file used: %s\n", f.bg.filename);
                    fprintf(fid, "# Reference file used: %s\n", f.bg.filename);
                    fprintf(fid, "# Background for reference file: %s\n", f.bg.filename);
                    fprintf(fid, "# Date processed: %s\n", datetime("now", Format="yyyy-MM-dd HH:mm"));
                    fprintf(fid, "# -----------------------\n");

                    % ---- Write table header ----
                    fprintf(fid, "Wavenumber,Intensity\n");

                    % ---- Write table data ----
                    pairs = [data{:, 'Wavenumber'}.'; data{:, 'Intensity'}.'];
                    fprintf(fid, "%.6f,%.6f\n", pairs);

                    % Close file
                    fclose(fid);

                catch errorobj
                    report = getReport(errorobj);
                    uialert(app.UIFigure, report, "Error", Interpreter="html")
                    return
                end
            end

            uialert(app.UIFigure, "Exported succesfully", "Export successful", Icon="success")

        end

    end

    % ==== Utilities ====
    methods (Access = private) 

        function loadState(app)
            % load data from StoredValues.mat into the app
            load("supporting files/StoredValues.mat", "state")
            app.vis_wl = state.vis_wl.Value;
            app.ref_str = state.ref_str.Value;
            app.calibration_str = state.calibration_str.Value;
            app.data_dir = state.data_dir;

        end

        function saveState(app)
            % save data from the app to StoredValues.mat
            state.data_dir = app.data_dir;
            state.vis_wl.Value = app.vis_wl;
            state.ref_str.Value = app.ref_str;
            state.calibration_str.Value = app.calibration_str;
            save("supporting files/StoredValues.mat", "state")
        end

    end

    % ==== Backend processes ====
    methods (Access = private) 

        function sortFiles(app)
            % this method extract data from the files chosen (checked) via
            % app.Tree and based on the values for ref and calibration, it
            % sorts those files info backgrounds, references, samples, and
            % calibration.
            % For more info about data extraction, see the File classes used throughout this app.

            % only include selected (checked) files
            selected_files = struct( ...
                "name", {}, ...
                "folder", {}, ...
                "date", {}, ...
                "bytes", {}, ...
                "isdir", {}, ...
                "datenum", {} ...
                );

            for i = 1:numel(app.Tree.CheckedNodes)
                entry = app.Tree.CheckedNodes(i).NodeData;
                % Only include if it's a dir-like struct (not a group node)
                if isstruct(entry) && isfield(entry, "name") && isfield(entry, "folder")
                    selected_files(end+1) = entry;
                end
            end

            % === sort files and initialize File objects ===
            app.sample_files = SampleFile.empty;
            app.bg_files = File.empty;
            app.ref_files = SignalFile.empty;
            app.calibration_files = SampleFile.empty;

            % iterate through the selected files and sort them
            for i = 1:numel(selected_files)
                fname = lower(selected_files(i).name);

                % sort based on file name
                is_calibration = contains(fname, app.calibration_str);
                is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
                is_sig = ~contains(fname, "bg") && ~contains(fname, "bkg") && ~contains(fname, "background");

                % append to the correct array and convert into the
                % corresponding File object (see the different classes in
                % supporting files)
                if is_sig
                    if is_sample
                        f = SampleFile(selected_files(i));
                        app.sample_files(end+1) = f;
                    elseif is_calibration
                        f = SampleFile(selected_files(i));
                        app.calibration_files(end+1) = f;
                    else
                        f = SignalFile(selected_files(i));
                        app.ref_files(end+1) = f;
                    end
                else
                    f = File(selected_files(i));
                    app.bg_files(end+1) = f;
                end
                % if possible check the region (IR wavelength) corresponding to each file
                app.checkRegion;
            end

        end

        function readSampleTable(app)
            % read app.sampleUITable (data sorting tab) to correctly
            % attribute the corresponding reference and background for each
            % sample file, the result is stored in a backend table

            % Initialize cell arrays to hold matched objects
            nRows = height(app.sampleUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);

            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                sampleName     = string(app.sampleUITable.Data{i, 1});
                backgroundName = string(app.sampleUITable.Data{i, 2});
                referenceName  = string(app.sampleUITable.Data{i, 3});

                % --- Match against object arrays ---
                % Find the SampleFile object
                samples{i} = app.sample_files(find([app.sample_files.filename] == sampleName, 1));

                % Find the Background File object
                backgrounds{i} = app.bg_files(find([app.bg_files.filename] == backgroundName, 1));

                % Find the SignalFile object
                references{i} = app.ref_files(find([app.ref_files.filename] == referenceName, 1));
            end

            % Create backend table with objects
            app.bkendSampleTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                VariableNames=["Sample", "Background", "Reference"] ...
                );

        end

        function readCalibTable(app)
            % read app.calibUITable (data sorting tab) to correctly
            % attribute the corresponding reference and background for each
            % calibtration file, the result is stored in a backend table

            % Initialize cell arrays to hold matched objects
            nRows = height(app.calibUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);

            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                sampleName    = string(app.calibUITable.Data{i, 1});
                backgroundName = string(app.calibUITable.Data{i, 2});
                referenceName  = string(app.calibUITable.Data{i, 3});

                % --- Match against object arrays ---
                % Find the SampleFile object
                samples{i} = app.calibration_files( ...
                    find([app.calibration_files.filename] == sampleName, 1) ...
                    );

                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                    );

                % Find the SignalFile object
                references{i} = app.ref_files( ...
                    find([app.ref_files.filename] == referenceName, 1) ...
                    );
            end

            % Create backend table with objects
            app.bkendCalibTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                VariableNames=["Calibration", "Background", "Reference"] ...
                );

        end

        function readRefTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.refUITable.Data);
            signals     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);

            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                signalName    = string(app.refUITable.Data{i, 1});
                backgroundName = string(app.refUITable.Data{i, 2});

                % --- Match against object arrays ---
                % Find the SignalFile object
                signals{i} = app.ref_files( ...
                    find([app.ref_files.filename] == signalName, 1) ...
                    );

                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                    );
            end

            % Create backend table with objects
            app.bkendRefTable = table( ...
                [signals{:}]', ...
                [backgrounds{:}]', ...
                VariableNames= ["Signal", "Background"] ...
                );

        end

        function checkRegion(app)
            % Helper function to extract valid region values from a file array
            function regions = extractRegions(fileArray)
                regions = [];
                for i = 1:numel(fileArray)
                    file = fileArray(i);
                    if isprop(file, "info") && isfield(file.info, "region") && ...
                            isnumeric(file.info.region) && isscalar(file.info.region)
                        regions(end+1) = file.info.region; %#ok<AGROW>
                    else
                        regions = NaN; % Use NaN to indicate invalid/missing region
                        return
                    end
                end
            end

            % Extract region values from each file type
            r1 = extractRegions(app.sample_files);
            r2 = extractRegions(app.bg_files);
            r3 = extractRegions(app.ref_files);
            r4 = extractRegions(app.calibration_files);

            % Combine all region values (if all are valid)
            if ~any(isnan([r1, r2, r3, r4]))
                all_regions = [r1, r2, r3, r4];
                if all(abs(all_regions - all_regions(1)) < 1e-6)
                    app.center_wl = all_regions(1);
                end
            end
        end

        function startDirectoryWatcher(app)
            % Stop existing watcher if running
            if ~isempty(app.dirWatcher) && isvalid(app.dirWatcher)
                stop(app.dirWatcher);
                delete(app.dirWatcher);
            end

            % Store initial file list
            app.lastFileList = {dir(fullfile(app.data_dir, '*.csv')).name};

            % Create timer to check folder every 2 seconds
            app.dirWatcher = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 2, ... % seconds
                'TimerFcn', @(~,~) app.updateFilesTree ...
                );

            start(app.dirWatcher);
        end

        % function updateFilesTree(app)
        %     % Stop if app is gone
        %     if ~isvalid(app) || ~isvalid(app.Tree)
        %         return;
        %     end
        %
        %     % Get current file list
        %     currentFiles = {dir(fullfile(app.data_dir, '*.csv')).name};
        %
        %     % Compare with last known list
        %     if ~isequal(sort(currentFiles), sort(app.lastFileList))
        %         app.lastFileList = currentFiles; % Update stored list
        %         app.buildfileTree;               % Rebuild tree
        %     end
        % end

        % % function startDirectoryWatcher(app)
        % %     % Stop existing watcher if running
        % %     if ~isempty(app.dirWatcher) && isvalid(app.dirWatcher)
        % %         stop(app.dirWatcher);
        % %         delete(app.dirWatcher);
        % %     end
        % %
        % %     % Store initial file list as cell array of names
        % %     app.lastFileList = {dir(fullfile(app.data_dir, '*.csv')).name};
        % %
        % %     % Create timer to check folder every 2 seconds
        % %     app.dirWatcher = timer( ...
        % %         'ExecutionMode', 'fixedRate', ...
        % %         'Period', 2, ... % seconds
        % %         'TimerFcn', @(~,~) app.updateFilesTree() ...
        % %         );
        % %
        % %     start(app.dirWatcher);
        % % end
        %
        % % function updateFilesTree(app)
        % %     % Stop if app is gone
        % %     if ~isvalid(app) || ~isvalid(app.Tree)
        % %         return;
        % %     end
        % %
        % %     % Get current file list (cell array of names)
        % %     currentFiles = {dir(fullfile(app.data_dir, '*.csv')).name};
        % %
        % %     % Compare with last known list
        % %     if ~isequal(sort(currentFiles), sort(app.lastFileList))
        % %         % Compute added and removed files
        % %         added = setdiff(currentFiles, app.lastFileList);
        % %         removed = setdiff(app.lastFileList, currentFiles);
        % %
        % %         % Update stored list
        % %         app.lastFileList = currentFiles;
        % %
        % %         % Update tree and tables
        % %         app.updateTreeAndTables(added, removed);
        % %     end
        % % end
        %
        % % function updateTreeAndTables(app, added, removed)
        % %     % Find "All Files" node (first child of Tree)
        % %     allNode = app.Tree.Children(1);
        % %
        % %     % --- Add new files ---
        % %     for i = 1:numel(added)
        % %         % Add to tree
        % %         uitreenode(allNode, "Text", added(i).name, "NodeData", added(i));
        % %
        % %         % Sort + table insert
        % %         app.addSingleFile(added(i));
        % %     end
        % %
        % %     % --- Remove deleted files ---
        % %     for i = 1:numel(removed)
        % %         % Remove from tree
        % %         nodes = allNode.Children;
        % %         matchNode = find(strcmp({nodes.Text}, removed(i).name), 1);
        % %         if matchNode
        % %             delete(nodes(matchNode));
        % %         end
        % %
        % %         % Remove from data + tables
        % %         app.removeSingleFile(removed(i).name);
        % %     end
        % % end
        %
        % % function addSingleFile(app, fileStruct)
        % %     fname = lower(fileStruct.name);
        % %     is_calibration = contains(fname, app.calibration_str);
        % %     is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
        % %     is_sig = ~contains(fname, "bg") && ~contains(fname, "bkg") && ~contains(fname, "background");
        % %
        % %     if is_sig
        % %         if is_sample
        % %             f = SampleFile(fileStruct);
        % %             app.sample_files(end+1) = f;
        % %             app.addRowToTable(f, 'sample');
        % %         elseif is_calibration
        % %             f = SampleFile(fileStruct);
        % %             app.calibration_files(end+1) = f;
        % %             app.addRowToTable(f, 'calibration');
        % %         else
        % %             f = SignalFile(fileStruct);
        % %             app.ref_files(end+1) = f;
        % %             app.addRowToTable(f, 'ref');
        % %         end
        % %     else
        % %         f = File(fileStruct);
        % %         app.bg_files(end+1) = f;
        % %         app.buildTables; % backgrounds affect dropdowns → refresh tables
        % %     end
        % %
        % %     app.checkRegion;
        % % end
        %
        % % function removeSingleFile(app, filename)
        % %     categories = {'sample_files', 'bg_files', 'ref_files', 'calibration_files'};
        % %     for c = categories
        % %         list = app.(c{1});
        % %         idx = find(strcmp({list.filename}, filename), 1);
        % %         if idx
        % %             app.(c{1})(idx) = [];
        % %
        % %             if strcmp(c{1}, 'bg_files')
        % %                 % Background removal changes dropdowns
        % %                 app.buildTables;
        % %             else
        % %                 app.removeRowFromTable(filename);
        % %             end
        % %             return;
        % %         end
        % %     end
        % % end
        %
        % % function addRowToTable(app, fileObj, category)
        % %     switch category
        % %         case 'sample'
        % %             newRow = {char(fileObj.filename), char(app.matchBackground(fileObj)), char(app.matchRef(fileObj))};
        % %             app.sampleUITable.Data(end+1, :) = newRow;
        % %
        % %         case 'calibration'
        % %             newRow = {char(fileObj.filename), char(app.matchBackground(fileObj)), char(app.matchRef(fileObj))};
        % %             app.calibUITable.Data(end+1, :) = newRow;
        % %
        % %         case 'ref'
        % %             newRow = {char(fileObj.filename), char(app.matchBackground(fileObj))};
        % %             app.refUITable.Data(end+1, :) = newRow;
        % %     end
        % % end
        %
        % % function removeRowFromTable(app, filename)
        %     tables = {app.sampleUITable, app.refUITable, app.calibUITable};
        %     for t = tables
        %         tbl = t{1};
        %         idx = strcmp(tbl.Data(:,1), filename);
        %         tbl.Data(idx, :) = [];
        %     end
        % end

    end

    % ==== UI management (private) ====
    methods (Access = private) 

        function updateFilesTree(app)
            % Stop if app is gone
            if ~isvalid(app) || ~isvalid(app.Tree)
                return;
            end

            % Get current CSV file list
            currentFiles = dir(fullfile(app.data_dir, '*.csv'));
            currentNames = {currentFiles.name};

            % If the tree is empty → full rebuild
            if isempty(app.Tree.Children)
                % Create parent node
                all_node = uitreenode(app.Tree, "Text", "All Files", "NodeData", []);
                % Add children
                for i = 1:numel(currentFiles)
                    uitreenode(all_node, Text=currentFiles(i).name, NodeData=currentFiles(i));
                end
                % Select all by default
                app.Tree.CheckedNodes = all_node;
                expand(app.Tree, "all");
                % Store list for next comparison
                app.lastFileList = currentNames;
                return;
            end

            % --- Incremental update mode ---
            % Get the "All Files" parent node
            all_node = app.Tree.Children(1);
            existingNames = arrayfun(@(n) n.Text, all_node.Children, 'UniformOutput', false);

            % --- Add new files ---
            filesToAdd = setdiff(currentNames, existingNames);
            for i = 1:numel(filesToAdd)
                idx = strcmp({currentFiles.name}, filesToAdd{i});
                uitreenode(all_node, Text=currentFiles(idx).name, NodeData=currentFiles(idx));
            end

            % --- Remove missing files ---
            filesToRemove = setdiff(existingNames, currentNames);

            for i = 1:numel(filesToRemove)
                % Find the node in the current children list
                nodeToRemove = [];
                for k = 1:numel(all_node.Children)
                    c = all_node.Children(k);  % now c is a uitreenode
                    if strcmp(c.Text, filesToRemove{i})
                        nodeToRemove = c;
                        break;
                    end
                end
                if ~isempty(nodeToRemove) && isvalid(nodeToRemove)
                    delete(nodeToRemove);
                end
            end

            % Update stored list
            app.lastFileList = currentNames;
        end

        function UpdatePcalibUIAxes(app)
            % when called, this method updates app.processedcalibUIAxes,
            % which is the axes on which the calibration absorbance and
            % NIST data is plotted.
            calibration = app.CalibrationfileDropDown.Value;
            calibration.convert_to_wn(app.vis_wl);

            multiplier = app.multiplierSlider.Value;
            baseline = app.baselineSlider.Value;
            app.polystyrene_std.adj_abs = ...
                app.polystyrene_std.ImaginaryRefractiveIndex .* multiplier + baseline;

            cla(app.processedcalibUIAxes)
            hold(app.processedcalibUIAxes, "on");

            plot(app.processedcalibUIAxes, app.polystyrene_std, "Wavenumber", "adj_abs");
            plot(app.processedcalibUIAxes, calibration.processed_data, "Wavenumber", "Absorbance");

            app.processedcalibUIAxes.XLim = app.xrangeSlider.Value;

            app.processedcalibUIAxes.XLabel.String = "Wavenumber (cm^{-1})";
            app.processedcalibUIAxes.YLabel.String = "Absorbance (a.u.)";

            legend(app.processedcalibUIAxes, "NIST", "Collected", Location="best");
            hold(app.processedcalibUIAxes, "off");
        end

        function plotRaw(app)
            % plot raw data for all checked nodes
            nodes = app.Tree1.CheckedNodes;

            cla(app.rawUIAxes)
            hold(app.rawUIAxes, "on");
            for i = 1:numel(nodes)
                f = nodes(i).NodeData;
                if isa(f, "File")
                    if strcmp(nodes(i).Parent.Parent.NodeData, "clean")
                        f.cleanRays;
                        plot(app.rawUIAxes, ...
                            f.raw_data, "Wavelength", "CleanIntensity", ...
                            "DisplayName", f.filename + " (clean)" ...
                            )
                    else
                        plot(app.rawUIAxes, ...
                            f.raw_data, "Wavelength", "Intensity", ...
                            "DisplayName", f.filename ...
                            )
                    end
                end

            end
            hold(app.rawUIAxes, "off");

            app.rawUIAxes.XLabel.String = "Wavelength (nm)";
            app.rawUIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.rawUIAxes, Location="best", Interpreter="none");

            app.rawUIAxes.XLim = app.raxrangeSlider.Value;

        end

        function plotProcessed(app)
            % plot processed data for all checked nodes
            nodes = app.Tree2.CheckedNodes;

            cla(app.processedUIAxes)
            hold(app.processedUIAxes, "on");
            for i = 1:numel(nodes)
                f = nodes(i).NodeData;
                if isa(f, "File")
                    plot( ...
                        app.processedUIAxes, ...
                        f.processed_data, "Wavenumber", "Intensity", ...
                        "DisplayName", f.filename ...
                        )
                end
            end
            hold(app.processedUIAxes, "off");

            app.processedUIAxes.XLabel.String = "Wavenumber (cm^{-1})";
            app.processedUIAxes.YLabel.String = "Intensity (a.u.)";
            legend(app.processedUIAxes, Location="best", Interpreter="none");

            app.processedUIAxes.XLim = app.xrangeSlider_2.Value;

        end

        function plotCalibration(app, calib)
            ref = calib.ref;
            bg = calib.bg;

            cla(app.rawcalibUIAxes, "reset")

            hold(app.rawcalibUIAxes, "on");
            plot( ...
                app.rawcalibUIAxes, ...
                bg.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", bg.filename ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                ref.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", ref.filename ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                calib.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", calib.filename ...
                )
            hold(app.rawcalibUIAxes, "off");

            app.rawcalibUIAxes.XLabel.String = "Wavelength (nm)";
            app.rawcalibUIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.rawcalibUIAxes, "Location", "best", "Interpreter", "none");

            x_vals = calib.processed_data.Wavenumber;
            app.xrangeSlider.Limits = [min(x_vals), max(x_vals)];
            if isfield(calib.info, "region") && isnumeric(calib.info.region) && isscalar(calib.info.region)
                um = calib.info.region;
                min_x = 1e4 / um - app.bandwidth / 2;
                max_x = 1e4 / um + app.bandwidth / 2;
                app.xrangeSlider.Value = [min_x, max_x];
            else
                app.xrangeSlider.Value = [min(x_vals), max(x_vals)];
            end

            cla(app.processedcalibUIAxes, "reset")

            app.UpdatePcalibUIAxes();

        end

        function buildTree1(app)
            % prepare all tree nodes and subnodes
            delete(app.Tree1.Children); % clean up old nodes

            % build parent nodes
            raw_node = uitreenode(app.Tree1, Text="Raw data", NodeData=[]);
            clean_node = uitreenode(app.Tree1, Text="Cleaned data", NodeData="clean");

            sample_node = uitreenode(raw_node, Text="Samples", NodeData=[]);
            ref_node = uitreenode(raw_node, Text="References", NodeData=[]);
            bg_node = uitreenode(raw_node, Text="Backgrounds", NodeData=[]);
            clean_sample_node = uitreenode(clean_node, Text="Samples", NodeData=[]);
            clean_ref_node = uitreenode(clean_node, Text="References", NodeData=[]);
            clean_bg_node = uitreenode(clean_node, Text="Backgrounds", NodeData=[]);

            % build children nodes for each file
            for i = 1:numel(app.sample_files)
                uitreenode(sample_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
                uitreenode(clean_sample_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
            end

            for i = 1:numel(app.ref_files)
                uitreenode(ref_node, Text=app.ref_files(i).filename, NodeData=app.ref_files(i));
                uitreenode(clean_ref_node, Text=app.ref_files(i).filename, NodeData=app.ref_files(i));
            end

            for i = 1:numel(app.bg_files)
                uitreenode(bg_node, Text=app.bg_files(i).filename, NodeData=app.bg_files(i));
                uitreenode(clean_bg_node, Text=app.bg_files(i).filename, NodeData=app.bg_files(i));
            end

            % select some nodes by default and expand the tree
            app.Tree1.CheckedNodes = clean_node;
            expand(app.Tree1);
        end

        function buildTables(app)
            ref_data = cell(numel(app.ref_files), 2);
            if isscalar(app.ref_files)
                refs = cell(1, numel(app.ref_files) + 1);
                refs{numel(app.ref_files) + 1} = 'empty';
            else
                refs = cell(1, numel(app.ref_files));
            end
            for i = 1:numel(app.ref_files)
                f = app.ref_files(i);
                ref_data{i, 1} = char(f.filename);
                ref_data{i, 2} = char(app.matchBackground(f));

                refs{1, i} = char(f.filename);
            end

            sample_data = cell(numel(app.sample_files), 3);
            for i = 1:numel(app.sample_files)
                f = app.sample_files(i);
                sample_data{i, 1} = char(f.filename);
                sample_data{i, 2} = char(app.matchBackground(f));
                sample_data{i, 3} = char(app.matchRef(f));
            end

            calib_data = cell(numel(app.calibration_files), 3);
            for i = 1:numel(app.calibration_files)
                f = app.calibration_files(i);
                calib_data{i, 1} = char(f.filename);
                calib_data{i, 2} = char(app.matchBackground(f));
                calib_data{i, 3} = char(app.matchRef(f));
            end

            if isscalar(app.bg_files)
                bgs = cell(1, numel(app.bg_files) + 1);
                bgs{numel(app.bg_files) + 1} = 'empty';
            else
                bgs = cell(1, numel(app.bg_files));
            end
            for i = 1:numel(app.bg_files)
                bgs{1, i} = char(app.bg_files(i).filename);
            end

            app.refUITable.ColumnName = {"Signal", "Background"};
            app.refUITable.Data = ref_data;
            app.refUITable.ColumnFormat = {[] bgs};
            app.refUITable.ColumnEditable = true;

            app.sampleUITable.ColumnName = {"Sample", "Background", "Reference"};
            app.sampleUITable.Data = sample_data;
            app.sampleUITable.ColumnFormat = {[] bgs refs};
            app.sampleUITable.ColumnEditable = true;

            app.calibUITable.ColumnName = {"Calibration", "Background", "Reference"};
            app.calibUITable.Data = calib_data;
            app.calibUITable.ColumnFormat = {[] bgs refs};
            app.calibUITable.ColumnEditable = true;

        end

        function buildTree2(app)
            % prepare all tree nodes and subnodes
            delete(app.Tree2.Children);
            all_node = uitreenode(app.Tree2, Text="All Files", NodeData=[]);

            for i = 1:numel(app.sample_files)
                uitreenode(all_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
            end

            % select all nodes by default and expand the tree
            app.Tree2.CheckedNodes = all_node;
            expand(app.Tree2, "all");
        end

        % function buildfileTree(app)
        %     detected_files = dir(fullfile(app.data_dir, "*.csv"));
        % 
        %     if isempty(detected_files)
        %         uialert(app.UIFigure, "No csv files detected in the selected directory", "Error")
        %         return;
        %     end
        % 
        %     % prepare all tree nodes and subnodes
        %     delete(app.Tree.Children);
        %     all_node = uitreenode(app.Tree, "Text", "All Files", "NodeData", []);
        % 
        %     for i = 1:numel(detected_files)
        %         uitreenode(all_node, Text=detected_files(i).name, NodeData=detected_files(i));
        %     end
        % 
        %     % select all nodes by default and expand the tree
        %     app.Tree.CheckedNodes = all_node;
        %     expand(app.Tree, "all");
        % end

        function buildCleaningTable(app)
            tableData = {};  % Initialize empty cell array

            % Helper to extract info from any file array
            function extractData(fileArray)
                for i = 1:numel(fileArray)
                    f = fileArray(i);

                    % Extract properties
                    tableData(end+1, :) = { ...
                        char(f.filename), ...
                        f.tf, ...
                        f.window ...
                        };
                end
            end

            % Process each file group
            extractData(app.sample_files);
            extractData(app.bg_files);
            extractData(app.ref_files);
            extractData(app.calibration_files);

            % Update cleaningUITable
            app.cleaningUITable.Data = tableData;
            app.cleaningUITable.ColumnName = {"Filename", "Threshold Factor", "Window"};
        end

    end

    % ==== UI management (public) ====
    methods (Access = public) 

        function enableSettingsButton(app)
            % Re-enable the settings button
            app.AdditionalSettingsButton.Enable = "on";
        end

        function updateCleaningTable(app)
            app.buildCleaningTable;

            % Re-enable the settings button
            app.EditSelectedButton.Enable = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, "center");

            app.polystyrene_std = readtable("polystyrene_imaginary_refractive_index.csv"); % load NIST polystyrene data

            % load starting parameters
            try
                app.loadState;
                app.wdField.Value = string(app.data_dir);
            catch
                app.vis_wl = 793;
                app.ref_str = "zqz";
                app.calibration_str = "polystyrene";
                app.wdField.Value = "None";
            end

            % initialize vis calibration widgets
            app.Spinner.Value = app.vis_wl;

            app.ProcesseddataTab.Parent = []; % hide processed data tab until data is processed
            app.sampleUITableCellEdit;        % makes sure button is disabled until tables are filled
            app.ApplychangesButton_2.Enable = "off";

            if app.wdField.Value ~= "None"
                app.updateFilesTree;
                app.sortFiles;      % Full sort the first time
                app.buildTables;    % Full table build the first time

                % Start watching for changes
                app.startDirectoryWatcher;
            end

        end

        % Button pushed function: SetchangeworkingdirectoryButton
        function SetchangeworkingdirectoryButtonPushed(app, event)
            try
                app.data_dir = uigetdir(app.data_dir);
            catch
                app.data_dir = uigetdir();
            end
            if isequal(app.data_dir, 0)
                return; % user canceled
            end

            app.wdField.Value = string(app.data_dir);

            % Initial build from scratch
            app.updateFilesTree;
            app.sortFiles;      % Full sort the first time
            app.buildTables;    % Full table build the first time

            % Start watching for changes
            app.startDirectoryWatcher;
        end

        % Value changed function: ReferencestringEditField
        function ReferencestringEditFieldValueChanged(app, event)
            app.ref_str = string(app.ReferencestringEditField.Value);

        end

        % Value changed function: CalibrationstringEditField
        function CalibrationstringEditFieldValueChanged(app, event)
            app.calibration_str = string(app.CalibrationstringEditField.Value);

        end

        % Button pushed function: SortfilesButton
        function SortfilesButtonPushed(app, event)
            % trying to get rid of this
            
            % % update storing strings
            % app.calibration_str = string(app.CalibrationstringEditField.Value);
            % app.ref_str = string(app.ReferencestringEditField.Value);


            app.sortFiles;     % sort files into bg, ref, sample, and calibration

            % make sure every file needed is present
            files = {app.bg_files, app.sample_files, app.ref_files, app.calibration_files};
            names = {"Background files", "Sample files", "Reference files", "Calibration files"};

            for i = 1:4
                if isempty(files{i})
                    uialert(app.UIFigure, sprintf("%s are missing.", names{i}), "Error");
                    return;
                end
            end


            app.buildTree1;            % initialize the raw data tab tree
            app.buildTables;           % initialize the data sorting tab tables
            app.sampleUITableCellEdit; % makes sure button is disabled until tables are filled

            % initialize raw data tab widgets (slider)
            x_vals = app.sample_files(1).raw_data.Wavelength;
            app.raxrangeSlider.Limits = [min(x_vals), max(x_vals)];
            app.raxrangeSlider.Value = [min(x_vals), max(x_vals)];

            % initialize cleaning tables
            app.buildCleaningTable;

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.vis_wl = app.Spinner.Value;
            app.UpdatePcalibUIAxes();

        end

        % Value changed function: baselineSlider, multiplierSlider, 
        % ...and 1 other component
        function baselineSliderValueChanged(app, event)
            app.UpdatePcalibUIAxes();

        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            % read the tables and initial
            app.readRefTable;
            app.readSampleTable;
            app.readCalibTable;
            app.processFiles;
            app.buildTree2;
            app.ProcesseddataTab.Parent = app.TabGroup; % Make the tab visible

            % Prepare dropdown for calibration
            app.CalibrationfileDropDown.Items = [app.calibration_files.filename];
            app.CalibrationfileDropDown.ItemsData = app.calibration_files;

            % prepare the sliders
            x_vals = app.sample_files(1).processed_data.Wavenumber;
            app.xrangeSlider_2.Limits = [min(x_vals), max(x_vals)];
            if ~isnan(app.center_wl)
                um = app.sample_files(1).info.region;
                min_x = 1e4 / um - app.bandwidth / 2;
                max_x = 1e4 / um + app.bandwidth / 2;
                app.xrangeSlider_2.Value = [min_x, max_x];
            else
                app.xrangeSlider_2.Value = [min(x_vals), max(x_vals)];
            end

            app.plotCalibration(app.calibration_files(1));

            app.ApplychangesButton_2.Enable = "on";

        end

        % Callback function: RawdataTab, Tree1, raxrangeSlider
        function RawdataTabButtonDown(app, event)
            app.plotRaw;

        end

        % Callback function: ProcesseddataTab, Tree2, xrangeSlider_2
        function ProcesseddataTabButtonDown(app, event)
            app.plotProcessed;
        end

        % Value changed function: CalibrationfileDropDown
        function CalibrationfileDropDownValueChanged(app, event)
            value = app.CalibrationfileDropDown.Value;
            app.plotCalibration(value);

        end

        % Cell edit callback: calibUITable, refUITable, sampleUITable
        function sampleUITableCellEdit(app, event)
            % Check each table individually
            tables = {app.calibUITable, app.refUITable, app.sampleUITable};
            allFilled = true;

            for i = 1:numel(tables)
                data = tables{i}.Data;

                % Skip if data is empty or contains empty/invalid strings
                if isempty(data) || any(cellfun(@(c) isempty(c) || isequal(c, '') || isequal(c, 'empty'), data(:)))
                    allFilled = false;
                    break;
                end
            end

            % Enable or disable button
            app.ApplychangesButton.Enable = matlab.lang.OnOffSwitchState(allFilled);

        end

        % Button pushed function: InformationhelpButton
        function InformationhelpButtonPushed(app, event)
            web("https://github.com/silanglois/Process-SFG-data", "-browser");
            message = [
                "Opening in your browser. If it doesn't appear, please check your taskbar.<br>" ...
                "You can also click <a href=""https://github.com/silanglois/Process-SFG-data"">this link</a> to open it manually." ...
                ];
            uialert(app.UIFigure, message, "Information", Icon="info", Interpreter="html");

        end

        % Button pushed function: AdditionalSettingsButton
        function AdditionalSettingsButtonPushed(app, event)
            app.AdditionalSettingsButton.Enable = "off";
            app.settingsApp = settings_app(app, app.bandwidth, app.simon);          % Create the settings dialog

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveState;
            delete(app.settingsApp)
            delete(app.cleaningApp)
            delete(app)

        end

        % Button pushed function: EditSelectedButton
        function EditSelectedButtonPushed(app, event)
            files = app.selectedFiles(app.cleaningUITable);
            if isempty(files)
                message = "First select files to edit by clicking on the corresponding table row.";
                uialert(app.UIFigure, message, "Error", Icon="error")
            else
                app.EditSelectedButton.Enable = "off";
                app.cleaningApp = cleaning_app(app, files);
            end

        end

        % Button pushed function: ApplychangesButton_2
        function ApplychangesButton_2Pushed(app, event)
            app.processFiles;

        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            app.export(app.sample_files);

        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            nodes = app.Tree2.CheckedNodes;
            export_files = SampleFile.empty;
            for i = 1:numel(nodes)
                if isa(nodes(i).NodeData, "SampleFile")
                    export_files(end+1) = nodes(i).NodeData;
                end
            end
            app.export(export_files)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatasortingTab = uitab(app.TabGroup);
            app.DatasortingTab.Title = 'Data sorting';
            app.GridLayout10 = uigridlayout(app.DatasortingTab);
            app.LoadandsortdatafilesPanel = uipanel(app.GridLayout10);
            app.LoadandsortdatafilesPanel.Title = 'Load and sort data files';
            app.GridLayout12 = uigridlayout(app.LoadandsortdatafilesPanel);
            app.ReferencestringEditFieldLabel = uilabel(app.GridLayout12);
            app.ReferencestringEditFieldLabel.Text = 'Reference string';
            app.ReferencestringEditField = uieditfield(app.GridLayout12, 'text');
            app.ReferencestringEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencestringEditFieldValueChanged, true);
            app.ReferencestringEditField.Value = 'zqz';
            app.CalibrationstringEditFieldLabel = uilabel(app.GridLayout12);
            app.CalibrationstringEditFieldLabel.Text = 'Calibration string';
            app.CalibrationstringEditField = uieditfield(app.GridLayout12, 'text');
            app.CalibrationstringEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationstringEditFieldValueChanged, true);
            app.CalibrationstringEditField.Value = 'polystyrene';
            app.SortfilesButton = uibutton(app.GridLayout12, 'push');
            app.SortfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SortfilesButtonPushed, true);
            app.SortfilesButton.Text = 'Sort files';
            app.SetchangeworkingdirectoryButton = uibutton(app.GridLayout12, 'push');
            app.SetchangeworkingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetchangeworkingdirectoryButtonPushed, true);
            app.SetchangeworkingdirectoryButton.Text = 'Set/change working directory';
            app.wdField = uieditfield(app.GridLayout12, 'text');
            app.wdField.Editable = 'off';
            app.CurrentdirectoryLabel = uilabel(app.GridLayout12);
            app.CurrentdirectoryLabel.Text = 'Current directory: ';
            app.Tree = uitree(app.GridLayout12, 'checkbox');
            app.SelectthefilesyouwanttoprocessLabel = uilabel(app.GridLayout12);
            app.SelectthefilesyouwanttoprocessLabel.Text = 'Select the files you want to process:';
            app.ApplychangesButton = uibutton(app.GridLayout10, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.refUITable = uitable(app.GridLayout10);
            app.refUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.refUITable.RowName = {};
            app.refUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.sampleUITable = uitable(app.GridLayout10);
            app.sampleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.sampleUITable.RowName = {};
            app.sampleUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.calibUITable = uitable(app.GridLayout10);
            app.calibUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.calibUITable.RowName = {};
            app.calibUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.ReferencesLabel = uilabel(app.GridLayout10);
            app.ReferencesLabel.Text = 'References';
            app.ViswavelengthcalibrationLabel = uilabel(app.GridLayout10);
            app.ViswavelengthcalibrationLabel.Text = 'Vis wavelength calibration';
            app.SamplesLabel = uilabel(app.GridLayout10);
            app.SamplesLabel.Text = 'Samples';
            app.AdditionalSettingsButton = uibutton(app.GridLayout10, 'push');
            app.AdditionalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionalSettingsButtonPushed, true);
            app.AdditionalSettingsButton.Text = 'Additional Settings';
            app.InformationhelpButton = uibutton(app.GridLayout10, 'push');
            app.InformationhelpButton.ButtonPushedFcn = createCallbackFcn(app, @InformationhelpButtonPushed, true);
            app.InformationhelpButton.Text = 'Information/help';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout7 = uigridlayout(app.CalibrationTab);
            app.processedcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.processedcalibUIAxes, 'Processed')
            xlabel(app.processedcalibUIAxes, 'Frequency (cm-1)')
            ylabel(app.processedcalibUIAxes, 'Absorbance (a.u.)')
            zlabel(app.processedcalibUIAxes, 'Z')
            app.rawcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.rawcalibUIAxes, 'Raw')
            xlabel(app.rawcalibUIAxes, 'X')
            ylabel(app.rawcalibUIAxes, 'Y')
            zlabel(app.rawcalibUIAxes, 'Z')
            app.xrangeSliderLabel = uilabel(app.GridLayout7);
            app.xrangeSliderLabel.Text = 'x range';
            app.xrangeSlider = uislider(app.GridLayout7, 'range');
            app.xrangeSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSliderLabel = uilabel(app.GridLayout7);
            app.multiplierSliderLabel.Text = 'multiplier';
            app.multiplierSlider = uislider(app.GridLayout7);
            app.multiplierSlider.Limits = [0 20];
            app.multiplierSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSlider.Value = 10;
            app.baselineSliderLabel = uilabel(app.GridLayout7);
            app.baselineSliderLabel.Text = 'baseline';
            app.baselineSlider = uislider(app.GridLayout7);
            app.baselineSlider.Limits = [0 3];
            app.baselineSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.CalibrationfileDropDownLabel = uilabel(app.GridLayout7);
            app.CalibrationfileDropDownLabel.Text = 'Calibration file';
            app.CalibrationfileDropDown = uidropdown(app.GridLayout7);
            app.CalibrationfileDropDown.Items = {};
            app.CalibrationfileDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationfileDropDownValueChanged, true);
            app.CalibrationfileDropDown.Placeholder = 'First fill out the tables in the "Data sorting" tab.';
            app.CalibrationfileDropDown.Value = {};
            app.Spinner = uispinner(app.GridLayout7);
            app.Spinner.Step = 0.05;
            app.Spinner.ValueDisplayFormat = '%.2f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CalibratevisiblewavelengthLabel = uilabel(app.GridLayout7);
            app.CalibratevisiblewavelengthLabel.Text = 'Calibrate visible wavelength';
            app.RawdataTab = uitab(app.TabGroup);
            app.RawdataTab.Title = 'Raw data';
            app.RawdataTab.ButtonDownFcn = createCallbackFcn(app, @RawdataTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.RawdataTab);
            app.rawUIAxes = uiaxes(app.GridLayout3);
            xlabel(app.rawUIAxes, 'X')
            ylabel(app.rawUIAxes, 'Y')
            zlabel(app.rawUIAxes, 'Z')
            app.xrangeLabel = uilabel(app.GridLayout3);
            app.xrangeLabel.Text = 'x range';
            app.raxrangeSlider = uislider(app.GridLayout3, 'range');
            app.raxrangeSlider.ValueChangedFcn = createCallbackFcn(app, @RawdataTabButtonDown, true);
            app.Tree1 = uitree(app.GridLayout3, 'checkbox');
            % Assign Checked Nodes
            app.Tree1.CheckedNodesChangedFcn = createCallbackFcn(app, @RawdataTabButtonDown, true);
            app.SelectdatatoviewLabel_3 = uilabel(app.GridLayout3);
            app.SelectdatatoviewLabel_3.Text = 'Select data to view:';
            app.AdditionalcleaningTab = uitab(app.TabGroup);
            app.AdditionalcleaningTab.Title = 'Additional cleaning';
            app.GridLayout15 = uigridlayout(app.AdditionalcleaningTab);
            app.cleaningUITable = uitable(app.GridLayout15);
            app.cleaningUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.cleaningUITable.RowName = {};
            app.cleaningUITable.SelectionType = 'row';
            app.EditSelectedButton = uibutton(app.GridLayout15, 'push');
            app.EditSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @EditSelectedButtonPushed, true);
            app.EditSelectedButton.Text = 'Edit Selected';
            app.cleaningUITable_2 = uitable(app.GridLayout15);
            app.cleaningUITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.cleaningUITable_2.RowName = {};
            app.cleaningUITable_2.SelectionType = 'row';
            app.EditSelectedButton_2 = uibutton(app.GridLayout15, 'push');
            app.EditSelectedButton_2.Text = 'Edit Selected';
            app.AutomaticcleaningparametersLabel = uilabel(app.GridLayout15);
            app.AutomaticcleaningparametersLabel.Text = 'Automatic cleaning parameters';
            app.MannuallyinterpolatenotyetimplementedLabel = uilabel(app.GridLayout15);
            app.MannuallyinterpolatenotyetimplementedLabel.Text = 'Mannually interpolate (not yet implemented)';
            app.ApplychangesButton_2 = uibutton(app.GridLayout15, 'push');
            app.ApplychangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButton_2Pushed, true);
            app.ApplychangesButton_2.Text = 'Apply changes';
            app.ProcesseddataTab = uitab(app.TabGroup);
            app.ProcesseddataTab.Title = 'Processed data';
            app.ProcesseddataTab.ButtonDownFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.GridLayout = uigridlayout(app.ProcesseddataTab);
            app.processedUIAxes = uiaxes(app.GridLayout);
            xlabel(app.processedUIAxes, 'X')
            ylabel(app.processedUIAxes, 'Y')
            zlabel(app.processedUIAxes, 'Z')
            app.xrangeSlider_2Label = uilabel(app.GridLayout);
            app.xrangeSlider_2Label.Text = 'x range';
            app.xrangeSlider_2 = uislider(app.GridLayout, 'range');
            app.xrangeSlider_2.ValueChangedFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.ExportSelectedButton = uibutton(app.GridLayout, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Text = 'Export Selected';
            app.ExportAllButton = uibutton(app.GridLayout, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout14 = uigridlayout(app.Panel);
            app.Tree2 = uitree(app.GridLayout14, 'checkbox');
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.SelectdatatoviewLabel_4 = uilabel(app.GridLayout14);
            app.SelectdatatoviewLabel_4.Text = 'Select data to view:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFG_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end