classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DatasortingTab                 matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        sampleUITable                  matlab.ui.control.Table
        refUITable                     matlab.ui.control.Table
        ApplychangesButton             matlab.ui.control.Button
        LoadandsortdatafilesPanel      matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        CurrentdirectoryLabel          matlab.ui.control.Label
        wdField                        matlab.ui.control.EditField
        SetchangeworkingdirectoryButton  matlab.ui.control.Button
        LoadfilesButton                matlab.ui.control.Button
        CalibrationstringEditField     matlab.ui.control.EditField
        CalibrationstringEditFieldLabel  matlab.ui.control.Label
        ReferencestringEditField       matlab.ui.control.EditField
        ReferencestringEditFieldLabel  matlab.ui.control.Label
        CalibrationTab                 matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        baselineSlider                 matlab.ui.control.Slider
        baselineSliderLabel            matlab.ui.control.Label
        multiplierSlider               matlab.ui.control.Slider
        multiplierSliderLabel          matlab.ui.control.Label
        xrangeSlider                   matlab.ui.control.RangeSlider
        xrangeSliderLabel              matlab.ui.control.Label
        CalibratevisiblewavelengthPanel  matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Spinner                        matlab.ui.control.Spinner
        ViswlSlider                    matlab.ui.control.Slider
        SetcalibrationfilesPanel       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        UpdateplotsButton              matlab.ui.control.Button
        BackgroundusedDropDown         matlab.ui.control.DropDown
        BackgroundusedDropDownLabel    matlab.ui.control.Label
        ReferenceusedDropDown          matlab.ui.control.DropDown
        ReferenceusedLabel             matlab.ui.control.Label
        CalibrationdatafileDropDown    matlab.ui.control.DropDown
        CalibrationdatafileDropDownLabel  matlab.ui.control.Label
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        RawdataTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        AllTab                         matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        Slider8_3                      matlab.ui.control.RangeSlider
        Slider8Label_3                 matlab.ui.control.Label
        Slider7_3                      matlab.ui.control.RangeSlider
        Slider7Label_3                 matlab.ui.control.Label
        Tree_3                         matlab.ui.container.CheckBoxTree
        Node_3                         matlab.ui.container.TreeNode
        Node2_3                        matlab.ui.container.TreeNode
        Node3_3                        matlab.ui.container.TreeNode
        Node4_3                        matlab.ui.container.TreeNode
        UIAxes5_3                      matlab.ui.control.UIAxes
        SampleTab                      matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        ListBox_4                      matlab.ui.control.ListBox
        Slider8_2                      matlab.ui.control.RangeSlider
        Slider8Label_2                 matlab.ui.control.Label
        Slider7_2                      matlab.ui.control.RangeSlider
        Slider7Label_2                 matlab.ui.control.Label
        UIAxes5_2                      matlab.ui.control.UIAxes
        ReferenceTab                   matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        ListBox_3                      matlab.ui.control.ListBox
        Slider8                        matlab.ui.control.RangeSlider
        Slider8Label                   matlab.ui.control.Label
        Slider7                        matlab.ui.control.RangeSlider
        Slider7Label                   matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        BackgroundsTab                 matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        ListBox_2                      matlab.ui.control.ListBox
        ListBox                        matlab.ui.control.ListBox
        Slider13                       matlab.ui.control.RangeSlider
        Slider13Label                  matlab.ui.control.Label
        Slider12                       matlab.ui.control.RangeSlider
        Slider12Label                  matlab.ui.control.Label
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes9                        matlab.ui.control.UIAxes
        ProcesseddataTab               matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        Slider3_2                      matlab.ui.control.RangeSlider
        Slider3_2Label                 matlab.ui.control.Label
        Slider6_2                      matlab.ui.control.RangeSlider
        Slider6_2Label                 matlab.ui.control.Label
        SelectdatatoviewListBox        matlab.ui.control.ListBox
        SelectdatatoviewLabel_2        matlab.ui.control.Label
        UIAxes4_2                      matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Slider3                        matlab.ui.control.RangeSlider
        Slider3Label                   matlab.ui.control.Label
        Slider6                        matlab.ui.control.RangeSlider
        Slider6Label                   matlab.ui.control.Label
        SelectdatatoviewListBox_2      matlab.ui.control.ListBox
        SelectdatatoviewLabel          matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        AdditionalcleaningTab          matlab.ui.container.Tab
        CommingsoonLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)

        ref_str               % string of characters used to indicate a file corresponds to a reference (e.g. 'quartz')
        calibration_str       % string of characters used to indicate a file corresponds to a calibration (e.g. 'polystyrene')
        vis_wl                % wavelength of the visible beam
        
        data_dir              % directory in which to look or raw data files

        sample_files          % Cell array of SampleFile objects
        bg_files              % Cell array of File objects
        ref_files             % Cell array of SignalFile objects
        calibration_files     % Cell array of SignalFile objects
        polystyrene_std       % NIST data for polystyrene imaginary refrative index

        CachedCalibration
        bkendSampleTable
        bkendRefTable
    end
    
    methods (Access = private)
        
        function UpdateUIAxes7(app)
            calibration = app.CachedCalibration;
            calibration = calibration.convert_to_wn(app.vis_wl);
    
            multiplier = app.multiplierSlider.Value;
            baseline = app.baselineSlider.Value;
            app.polystyrene_std.adj_abs = ...
                app.polystyrene_std.ImaginaryRefractiveIndex .* multiplier + baseline;
    
            cla(app.UIAxes7)
            hold(app.UIAxes7, 'on');
    
            plot(app.UIAxes7, app.polystyrene_std, "Wavenumber", "adj_abs");
            plot(app.UIAxes7, calibration.processed_data, "Wavenumber", "Absorbance");
    
            % x_vals = calibration.processed_data.Wavenumber;
            app.UIAxes7.XLim = app.xrangeSlider.Value;
    
            app.UIAxes7.XLabel.String = 'Wavenumber (cm^{-1})';
            app.UIAxes7.YLabel.String = 'Absorbance (a.u.)';
    
            legend(app.UIAxes7, 'NIST', 'Collected');
            hold(app.UIAxes7, 'off');
        end
        
        function loadState(app)
            load('StoredValues.mat', 'state')
            app.vis_wl = state.vis_wl.Value;
            app.ref_str = state.ref_str.Value;
            app.calibration_str = state.calibration_str.Value;
            app.data_dir = state.data_dir;

        end
        
        function saveState(app)
            state.data_dir = app.data_dir;
            state.vis_wl.Value = app.vis_wl;
            state.ref_str.Value = app.ref_str;
            state.calibration_str.Value = app.calibration_str;
            save('StoredValues.mat', 'state')
        end
        
        function sortFiles(app, detected_files)
            
            app.sample_files = SampleFile.empty;
            app.bg_files = File.empty;
            app.ref_files = SignalFile.empty;
            app.calibration_files = SampleFile.empty;

            for i = 1:numel(detected_files)
                fname = lower(detected_files(i).name);
                is_calibration = contains(fname, app.calibration_str);
                is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
                is_sig = ~contains(fname, "bg") || contains(fname, "bkg") || contains(fname, "background");

                if is_sig
                    if is_sample
                        f = SampleFile(detected_files(i));
                        app.sample_files(end+1) = f;
                    elseif is_calibration
                        f = SampleFile(detected_files(i));
                        app.calibration_files(end+1) = f;
                    else
                        f = SignalFile(detected_files(i));
                        app.ref_files(end+1) = f;
                    end
                else
                    f = File(detected_files(i));
                    app.bg_files(end+1) = f;
                end
            end

        end
        
        function readSampleTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.sampleUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);
            
            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                sampleName    = string(app.sampleUITable.Data{i, 1});
                backgroundName = string(app.sampleUITable.Data{i, 2});
                referenceName  = string(app.sampleUITable.Data{i, 3});
            
                % --- Match against object arrays ---
                % Find the SampleFile object
                samples{i} = app.sample_files( ...
                    find([app.sample_files.filename] == sampleName, 1) ...
                );
            
                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                );
            
                % Find the SignalFile object
                references{i} = app.ref_files( ...
                    find([app.ref_files.filename] == referenceName, 1) ...
                );
            end
            
            % Create backend table with objects
            app.bkendSampleTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                'VariableNames', {'Sample', 'Background', 'Reference'} ...
            );

        end
        
        function readRefTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.refUITable.Data);
            signals     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            
            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                signalName    = string(app.refUITable.Data{i, 1});
                backgroundName = string(app.refUITable.Data{i, 2});
            
                % --- Match against object arrays ---
                % Find the SignalFile object
                signals{i} = app.ref_files( ...
                    find([app.ref_files.filename] == signalName, 1) ...
                );
            
                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                );
            end
            
            % Create backend table with objects
            app.bkendRefTable = table( ...
                [signals{:}]', ...
                [backgrounds{:}]', ...
                'VariableNames', {'Signal', 'Background'} ...
            );

        end
        
        function processFiles(app)
            for i = 1:height(app.bkendRefTable)
                signal = app.bkendRefTable.Signal(i);
                background = app.bkendRefTable.Background(i);
            
                % Assign background
                signal.bg_used = background;

                % subtract bg
                signal = signal.subtract_background(background);
            
                % % Update file in the table
                % app.bkendRefTable.Signal(i) = signal;
            end
            for i = 1:height(app.bkendSampleTable)
                sample = app.bkendSampleTable.Sample(i);
                background = app.bkendSampleTable.Background(i);
                reference = app.bkendSampleTable.Reference(i);
            
                % Assign the background and reference
                sample.bg_used = background;
                sample.ref_used = reference;

                % subtract bg, normalize, convert to wavenumber
                sample = sample.subtract_background(background);
                sample = sample.normalize(reference);
                sample = sample.convert_to_wn(app.vis_wl);
            
                % % Update the file in the table
                % app.bkendSampleTable.Sample(i) = sample;
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('classes'));
            app.polystyrene_std = readtable( ...
                "polystyrene_imaginary_refractive_index.csv");

            try
                app.loadState;
                app.wdField.Value = string(app.data_dir);
            catch
                app.vis_wl = 793;
                app.ref_str = "zqz";
                app.calibration_str = "polystyrene";
                app.wdField.Value = "None";
            end

            app.ViswlSlider.Value = app.vis_wl;
            app.Spinner.Value = app.vis_wl;
        end

        % Value changed function: ReferencestringEditField
        function ReferencestringEditFieldValueChanged(app, event)
            app.ref_str = app.ReferencestringEditField.Value;
            
        end

        % Value changed function: CalibrationstringEditField
        function CalibrationstringEditFieldValueChanged(app, event)
            app.calibration_str = app.CalibrationstringEditField.Value;
            
        end

        % Button pushed function: SetchangeworkingdirectoryButton
        function SetchangeworkingdirectoryButtonPushed(app, event)
            app.data_dir = uigetdir();
            app.wdField.Value = string(app.data_dir);
        end

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            detected_files = dir(fullfile(app.data_dir, '*.csv'));
            app.sortFiles(detected_files)
            
            % app.sample_files = SampleFile.empty;
            % app.bg_files = File.empty;
            % app.ref_files = SignalFile.empty;
            % app.calibration_files = SampleFile.empty;
            % 
            % for i = 1:numel(detected_files)
            %     fname = lower(detected_files(i).name);
            %     is_calibration = contains(fname, app.calibration_str);
            %     is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
            %     is_sig = ~contains(fname, "bg") || contains(fname, "bkg") || contains(fname, "background");
            % 
            %     if is_sig
            %         if is_sample
            %             f = SampleFile(detected_files(i));
            %             app.sample_files(end+1) = f;
            %         elseif is_calibration
            %             f = SampleFile(detected_files(i));
            %             app.calibration_files(end+1) = f;
            %         else
            %             f = SignalFile(detected_files(i));
            %             app.ref_files(end+1) = f;
            %         end
            %     else
            %         f = File(detected_files(i));
            %         app.bg_files(end+1) = f;
            %     end
            % end
            
            ref_data = cell(numel(app.ref_files), 2);
            refs = cell(1, numel(app.ref_files));
            for i = 1:numel(app.ref_files)
                ref_data{i, 1} = char(app.ref_files(i).filename);
                ref_data{i, 2} = '';
                refs{1, i} = char(app.ref_files(i).filename);
            end

            sample_data = cell(numel(app.sample_files), 3);
            for i = 1:numel(app.sample_files)
                sample_data{i, 1} = char(app.sample_files(i).filename);
                sample_data{i, 2} = '';
                sample_data{i, 3} = '';
            end

            bgs = cell(1, numel(app.bg_files));
            for i = 1:numel(app.bg_files)
                bgs{1, i} = char(app.bg_files(i).filename);
            end

            app.refUITable.ColumnName = {'Signal', 'Background'};
            app.refUITable.Data = ref_data;
            app.refUITable.ColumnFormat = {[] bgs};
            app.refUITable.ColumnEditable = true;

            app.sampleUITable.ColumnName = {'Sample', 'Background', 'Reference'};
            app.sampleUITable.Data = sample_data;
            app.sampleUITable.ColumnFormat = {[] bgs refs};
            app.sampleUITable.ColumnEditable = true;

            % Prepare dropdowns for calibration
            app.CalibrationdatafileDropDown.Items = [app.calibration_files.filename];
            app.CalibrationdatafileDropDown.ItemsData = app.calibration_files;
            
            app.ReferenceusedDropDown.Items = [app.ref_files.filename];
            app.ReferenceusedDropDown.ItemsData = app.ref_files;
            
            app.BackgroundusedDropDown.Items = [app.bg_files.filename];
            app.BackgroundusedDropDown.ItemsData = app.bg_files;


        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
            calibration = app.CalibrationdatafileDropDown.Value;
            ref =  app.ReferenceusedDropDown.Value;
            bg = app.BackgroundusedDropDown.Value;

            cla(app.UIAxes6, 'reset')

            hold(app.UIAxes6, 'on');
            plot( ...
                app.UIAxes6, ...
                bg.raw_data, "Wavelength", "Intensity" ...
                )
            plot( ...
                app.UIAxes6, ...
                ref.raw_data, "Wavelength", "Intensity" ...
                )
            plot( ...
                app.UIAxes6, ...
                calibration.raw_data, "Wavelength", "Intensity" ...
                )
            hold(app.UIAxes6, 'off');

            app.UIAxes6.XLabel.String = 'Wavelength (nm)';
            app.UIAxes6.YLabel.String = 'Intensity (cts.)';
            legend(app.UIAxes6, char(bg.filename), char(ref.filename), char(calibration.filename));

            ref = ref.subtract_background(bg);
            calibration = calibration.subtract_background(bg);
            calibration = calibration.normalize(ref);
            calibration = calibration.convert_to_wn(app.vis_wl);

            % convert from transmittance to absorbance
            calibration.processed_data.Intensity(calibration.processed_data.Intensity <= 0) = eps;
            calibration.processed_data.Absorbance = ...
                              -log10(calibration.processed_data.Intensity);
            app.CachedCalibration = calibration;


            x_vals = calibration.processed_data.Wavenumber;
            app.xrangeSlider.Limits = [min(x_vals), max(x_vals)];
            app.xrangeSlider.Value = [min(x_vals), max(x_vals)];

            cla(app.UIAxes7, 'reset')

            app.UpdateUIAxes7();

        end

        % Value changed function: ViswlSlider
        function ViswlSliderValueChanged(app, event)
            app.vis_wl = app.ViswlSlider.Value;
            app.Spinner.Value = app.vis_wl;
            app.UpdateUIAxes7();
            
        end

        % Value changed function: baselineSlider, multiplierSlider, 
        % ...and 1 other component
        function baselineSliderValueChanged(app, event)
            app.UpdateUIAxes7();
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.vis_wl = app.Spinner.Value;
            app.ViswlSlider.Value = app.vis_wl;
            app.UpdateUIAxes7();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveState;
            delete(app)
            
        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            app.readRefTable;
            app.readSampleTable;
            app.processFiles;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatasortingTab = uitab(app.TabGroup);
            app.DatasortingTab.Title = 'Data sorting';
            app.GridLayout10 = uigridlayout(app.DatasortingTab);
            app.LoadandsortdatafilesPanel = uipanel(app.GridLayout10);
            app.LoadandsortdatafilesPanel.Title = 'Load and sort data files';
            app.GridLayout12 = uigridlayout(app.LoadandsortdatafilesPanel);
            app.ReferencestringEditFieldLabel = uilabel(app.GridLayout12);
            app.ReferencestringEditFieldLabel.Text = 'Reference string';
            app.ReferencestringEditField = uieditfield(app.GridLayout12, 'text');
            app.ReferencestringEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencestringEditFieldValueChanged, true);
            app.ReferencestringEditField.Value = 'zqz';
            app.CalibrationstringEditFieldLabel = uilabel(app.GridLayout12);
            app.CalibrationstringEditFieldLabel.Text = 'Calibration string';
            app.CalibrationstringEditField = uieditfield(app.GridLayout12, 'text');
            app.CalibrationstringEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationstringEditFieldValueChanged, true);
            app.CalibrationstringEditField.Value = 'polystyrene';
            app.LoadfilesButton = uibutton(app.GridLayout12, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            app.SetchangeworkingdirectoryButton = uibutton(app.GridLayout12, 'push');
            app.SetchangeworkingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetchangeworkingdirectoryButtonPushed, true);
            app.SetchangeworkingdirectoryButton.Text = 'Set/change working directory';
            app.wdField = uieditfield(app.GridLayout12, 'text');
            app.wdField.Editable = 'off';
            app.CurrentdirectoryLabel = uilabel(app.GridLayout12);
            app.CurrentdirectoryLabel.Text = 'Current directory: ';
            app.ApplychangesButton = uibutton(app.GridLayout10, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.refUITable = uitable(app.GridLayout10);
            app.refUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.refUITable.RowName = {};
            app.sampleUITable = uitable(app.GridLayout10);
            app.sampleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.sampleUITable.RowName = {};
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout7 = uigridlayout(app.CalibrationTab);
            app.UIAxes6 = uiaxes(app.GridLayout7);
            title(app.UIAxes6, 'Raw')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout7);
            title(app.UIAxes7, 'Processed')
            xlabel(app.UIAxes7, 'Frequency (cm-1)')
            ylabel(app.UIAxes7, 'Absorbance (a.u.)')
            zlabel(app.UIAxes7, 'Z')
            app.SetcalibrationfilesPanel = uipanel(app.GridLayout7);
            app.SetcalibrationfilesPanel.Title = 'Set calibration files';
            app.GridLayout9 = uigridlayout(app.SetcalibrationfilesPanel);
            app.CalibrationdatafileDropDownLabel = uilabel(app.GridLayout9);
            app.CalibrationdatafileDropDownLabel.Text = 'Calibration data file';
            app.CalibrationdatafileDropDown = uidropdown(app.GridLayout9);
            app.ReferenceusedLabel = uilabel(app.GridLayout9);
            app.ReferenceusedLabel.Text = 'Reference used';
            app.ReferenceusedDropDown = uidropdown(app.GridLayout9);
            app.BackgroundusedDropDownLabel = uilabel(app.GridLayout9);
            app.BackgroundusedDropDownLabel.Text = 'Background used';
            app.BackgroundusedDropDown = uidropdown(app.GridLayout9);
            app.UpdateplotsButton = uibutton(app.GridLayout9, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.CalibratevisiblewavelengthPanel = uipanel(app.GridLayout7);
            app.CalibratevisiblewavelengthPanel.Title = 'Calibrate visible wavelength';
            app.GridLayout8 = uigridlayout(app.CalibratevisiblewavelengthPanel);
            app.ViswlSlider = uislider(app.GridLayout8);
            app.ViswlSlider.Limits = [790 796];
            app.ViswlSlider.ValueChangedFcn = createCallbackFcn(app, @ViswlSliderValueChanged, true);
            app.ViswlSlider.Value = 793;
            app.Spinner = uispinner(app.GridLayout8);
            app.Spinner.Step = 0.05;
            app.Spinner.ValueDisplayFormat = '%.2f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.xrangeSliderLabel = uilabel(app.GridLayout7);
            app.xrangeSliderLabel.Text = 'x range';
            app.xrangeSlider = uislider(app.GridLayout7, 'range');
            app.xrangeSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSliderLabel = uilabel(app.GridLayout7);
            app.multiplierSliderLabel.Text = 'multiplier';
            app.multiplierSlider = uislider(app.GridLayout7);
            app.multiplierSlider.Limits = [0 20];
            app.multiplierSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSlider.Value = 10;
            app.baselineSliderLabel = uilabel(app.GridLayout7);
            app.baselineSliderLabel.Text = 'baseline';
            app.baselineSlider = uislider(app.GridLayout7);
            app.baselineSlider.Limits = [0 3];
            app.baselineSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.RawdataTab = uitab(app.TabGroup);
            app.RawdataTab.Title = 'Raw data';
            app.GridLayout3 = uigridlayout(app.RawdataTab);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.AllTab = uitab(app.TabGroup2);
            app.AllTab.Title = 'All';
            app.GridLayout13 = uigridlayout(app.AllTab);
            app.UIAxes5_3 = uiaxes(app.GridLayout13);
            title(app.UIAxes5_3, 'Title')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            zlabel(app.UIAxes5_3, 'Z')
            app.Tree_3 = uitree(app.GridLayout13, 'checkbox');
            app.Node_3 = uitreenode(app.Tree_3);
            app.Node_3.Text = 'Node';
            app.Node2_3 = uitreenode(app.Node_3);
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.Node_3);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.Node_3);
            app.Node4_3.Text = 'Node4';
            app.Slider7Label_3 = uilabel(app.GridLayout13);
            app.Slider7Label_3.Text = 'Slider7';
            app.Slider7_3 = uislider(app.GridLayout13, 'range');
            app.Slider8Label_3 = uilabel(app.GridLayout13);
            app.Slider8Label_3.Text = 'Slider8';
            app.Slider8_3 = uislider(app.GridLayout13, 'range');
            app.SampleTab = uitab(app.TabGroup2);
            app.SampleTab.Title = 'Sample';
            app.GridLayout5 = uigridlayout(app.SampleTab);
            app.UIAxes5_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.Slider7Label_2 = uilabel(app.GridLayout5);
            app.Slider7Label_2.Text = 'Slider7';
            app.Slider7_2 = uislider(app.GridLayout5, 'range');
            app.Slider8Label_2 = uilabel(app.GridLayout5);
            app.Slider8Label_2.Text = 'Slider8';
            app.Slider8_2 = uislider(app.GridLayout5, 'range');
            app.ListBox_4 = uilistbox(app.GridLayout5);
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Item 1'};
            app.ReferenceTab = uitab(app.TabGroup2);
            app.ReferenceTab.Title = 'Reference';
            app.GridLayout4 = uigridlayout(app.ReferenceTab);
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Slider7Label = uilabel(app.GridLayout4);
            app.Slider7Label.Text = 'Slider7';
            app.Slider7 = uislider(app.GridLayout4, 'range');
            app.Slider8Label = uilabel(app.GridLayout4);
            app.Slider8Label.Text = 'Slider8';
            app.Slider8 = uislider(app.GridLayout4, 'range');
            app.ListBox_3 = uilistbox(app.GridLayout4);
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Item 1'};
            app.BackgroundsTab = uitab(app.TabGroup2);
            app.BackgroundsTab.Title = 'Backgrounds';
            app.GridLayout6 = uigridlayout(app.BackgroundsTab);
            app.UIAxes9 = uiaxes(app.GridLayout6);
            title(app.UIAxes9, 'Sample backgrounds')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout6);
            title(app.UIAxes8, 'Reference backgrounds')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.Slider12Label = uilabel(app.GridLayout6);
            app.Slider12Label.Text = 'Slider12';
            app.Slider12 = uislider(app.GridLayout6, 'range');
            app.Slider13Label = uilabel(app.GridLayout6);
            app.Slider13Label.Text = 'Slider13';
            app.Slider13 = uislider(app.GridLayout6, 'range');
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.ListBox_2 = uilistbox(app.GridLayout6);
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'Item 1'};
            app.ProcesseddataTab = uitab(app.TabGroup);
            app.ProcesseddataTab.Title = 'Processed data';
            app.GridLayout = uigridlayout(app.ProcesseddataTab);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SelectdatatoviewLabel = uilabel(app.GridLayout2);
            app.SelectdatatoviewLabel.Text = 'Select data to view:';
            app.SelectdatatoviewListBox_2 = uilistbox(app.GridLayout2);
            app.SelectdatatoviewListBox_2.Multiselect = 'on';
            app.SelectdatatoviewListBox_2.Value = {'Item 1'};
            app.Slider6Label = uilabel(app.GridLayout2);
            app.Slider6Label.Text = 'Slider6';
            app.Slider6 = uislider(app.GridLayout2, 'range');
            app.Slider3Label = uilabel(app.GridLayout2);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.GridLayout2, 'range');
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.UIAxes4_2 = uiaxes(app.GridLayout2_2);
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.SelectdatatoviewLabel_2 = uilabel(app.GridLayout2_2);
            app.SelectdatatoviewLabel_2.Text = 'Select data to view:';
            app.SelectdatatoviewListBox = uilistbox(app.GridLayout2_2);
            app.SelectdatatoviewListBox.Multiselect = 'on';
            app.SelectdatatoviewListBox.Value = {'Item 1'};
            app.Slider6_2Label = uilabel(app.GridLayout2_2);
            app.Slider6_2Label.Text = 'Slider6';
            app.Slider6_2 = uislider(app.GridLayout2_2, 'range');
            app.Slider3_2Label = uilabel(app.GridLayout2_2);
            app.Slider3_2Label.Text = 'Slider3';
            app.Slider3_2 = uislider(app.GridLayout2_2, 'range');
            app.AdditionalcleaningTab = uitab(app.TabGroup);
            app.AdditionalcleaningTab.Title = 'Additional cleaning';
            app.CommingsoonLabel = uilabel(app.AdditionalcleaningTab);
            app.CommingsoonLabel.Text = 'Comming soon...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end