classdef background_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Spinner_3                    matlab.ui.control.Spinner
        OffsetinterceptLabel         matlab.ui.control.Label
        Spinner_2                    matlab.ui.control.Spinner
        OffsetslopeLabel             matlab.ui.control.Label
        SmoothbackgroundnotrecommendedCheckBox  matlab.ui.control.CheckBox
        SmoothingfactorSpinner       matlab.ui.control.Spinner
        SmoothingfactorSpinnerLabel  matlab.ui.control.Label
        CancelButton                 matlab.ui.control.StateButton
        SaveButton                   matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        smoothFactor double = 0   % smoothing factor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SmoothingfactorSpinner.Enable = "off";

        end

        % Value changed function: SmoothbackgroundnotrecommendedCheckBox
        function SmoothbackgroundnotrecommendedCheckBoxValueChanged(app, event)
            if app.SmoothbackgroundnotrecommendedCheckBox.Value
                app.SmoothingfactorSpinner.Enable = "on";
            else
                app.SmoothingfactorSpinner.Enable = "off";
            end
            
        end

        % Value changed function: SmoothingfactorSpinner
        function SmoothingfactorSpinnerValueChanged(app, event)
            app.smoothFactor = app.SmoothingfactorSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveButton = uibutton(app.GridLayout, 'state');
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout, 'state');
            app.CancelButton.Text = 'Cancel';
            app.SmoothingfactorSpinnerLabel = uilabel(app.GridLayout);
            app.SmoothingfactorSpinnerLabel.Text = 'Smoothing factor';
            app.SmoothingfactorSpinner = uispinner(app.GridLayout);
            app.SmoothingfactorSpinner.Step = 0.05;
            app.SmoothingfactorSpinner.Limits = [0 1];
            app.SmoothingfactorSpinner.ValueDisplayFormat = '%.2f';
            app.SmoothingfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothingfactorSpinnerValueChanged, true);
            app.SmoothbackgroundnotrecommendedCheckBox = uicheckbox(app.GridLayout);
            app.SmoothbackgroundnotrecommendedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothbackgroundnotrecommendedCheckBoxValueChanged, true);
            app.SmoothbackgroundnotrecommendedCheckBox.Text = 'Smooth background (not recommended)';
            app.OffsetslopeLabel = uilabel(app.GridLayout);
            app.OffsetslopeLabel.Text = 'Offset slope';
            app.Spinner_2 = uispinner(app.GridLayout);
            app.OffsetinterceptLabel = uilabel(app.GridLayout);
            app.OffsetinterceptLabel.Text = 'Offset intercept';
            app.Spinner_3 = uispinner(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = background_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end