classdef settings_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IRpulsebandwidthnmSpinner       matlab.ui.control.Spinner
        IRpulsebandwidthnmSpinnerLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        UseSimonssecretoptionsnotrecommendedCheckBox  matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp
        bandwidth double
        simon logical
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, bandwidth, simon)
            movegui(app.UIFigure, "center");
            app.MainApp = MainApp;
            app.bandwidth = bandwidth;
            app.simon = simon;

            app.IRpulsebandwidthnmSpinner.Value = app.bandwidth;
            app.UseSimonssecretoptionsnotrecommendedCheckBox.Value = app.simon;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.bandwidth = app.IRpulsebandwidthnmSpinner.Value;
            app.simon = app.UseSimonssecretoptionsnotrecommendedCheckBox.Value;

            app.MainApp.bandwidth = app.bandwidth;
            app.MainApp.simon = app.simon;
            enableSettingsButton(app.MainApp);
            delete(app);

        end

        % Value changed function: IRpulsebandwidthnmSpinner
        function IRpulsebandwidthnmSpinnerValueChanged(app, event)
            app.bandwidth = app.IRpulsebandwidthnmSpinner.Value;
            
        end

        % Value changed function: 
        % UseSimonssecretoptionsnotrecommendedCheckBox
        function UseSimonssecretoptionsnotrecommendedCheckBoxValueChanged(app, event)
            app.simon = app.UseSimonssecretoptionsnotrecommendedCheckBox.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Enable the Plot Options button in main app, if the app is
            % still open (does not seem to work)
            if isvalid(app.MainApp)
                app.MainApp.AdditionalSettingsButton.Enable = "on";
            end
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'surprise.jpg');
            app.UseSimonssecretoptionsnotrecommendedCheckBox = uicheckbox(app.GridLayout);
            app.UseSimonssecretoptionsnotrecommendedCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSimonssecretoptionsnotrecommendedCheckBoxValueChanged, true);
            app.UseSimonssecretoptionsnotrecommendedCheckBox.Text = 'Use Simon''s secret options (not recommended)';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.IRpulsebandwidthnmSpinnerLabel = uilabel(app.GridLayout);
            app.IRpulsebandwidthnmSpinnerLabel.Text = 'IR pulse bandwidth (nm)';
            app.IRpulsebandwidthnmSpinner = uispinner(app.GridLayout);
            app.IRpulsebandwidthnmSpinner.Step = 25;
            app.IRpulsebandwidthnmSpinner.ValueDisplayFormat = '%.0f';
            app.IRpulsebandwidthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @IRpulsebandwidthnmSpinnerValueChanged, true);
            app.IRpulsebandwidthnmSpinner.Value = 950;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end